classdef Kolke_185ic_projekt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        UruchomiListBox     matlab.ui.control.ListBox
        LiczySwitchLabel    matlab.ui.control.Label
        LiczySwitch         matlab.ui.control.ToggleSwitch
        ZmarliCheckBox      matlab.ui.control.CheckBox
        ZaraeniCheckBox     matlab.ui.control.CheckBox
        OzdrowiecyCheckBox  matlab.ui.control.CheckBox
    end

    methods (Access = private) 
        function [panstwo, zmarli, zarazeni, ozdrowiency] = zbieranieDanych(app,panstwo, regionPop, liczbaPop, panstwoLista, zmarliLista, zarazeniLista, ozdrowiencyLista,infWskLista ,smWskLista)
                panstwo = string(panstwo);
                zarazeni = zeros(31,1);
                narazeni=zeros(31,1);
                ozdrowiency = zeros(31,1);
                zmarli = zeros(31,1);
                wskZdr=0;
                infWsk=0;
                smWsk=0;
                iterator=0;
                srednia=0;
                for szukane=1:size(panstwoLista(:,1))
                    iterator=iterator+1;       
                    panstwoLista=string(panstwoLista);
                    if strcmpi(strtrim(panstwo), strtrim(panstwoLista(iterator,1)))
                        zmarli(1,1) =zmarli(1,1) +zmarliLista(iterator,1);    
                        zarazeni(1,1) = zarazeni(1,1)+zarazeniLista(iterator,1);
                        ozdrowiency(1,1)=ozdrowiency(1,1)+ozdrowiencyLista(iterator,1);
                        infWsk =infWsk+infWskLista(iterator,1);
                        smWsk = smWsk+smWskLista(iterator,1);
                        srednia=srednia+1;
                    end                         
                end
                infWsk = infWsk/srednia;
                infWsk=infWsk/100000
                smWsk = smWsk/srednia;
                smWsk = smWsk/100;
                iterator = 0;
                populacja = 0;
                for porow = 1:size(regionPop(:,1))
                    if strcmpi((strtrim(panstwo)),(strtrim(regionPop(porow,1))))
                        populacja = liczbaPop(porow,1)
                        break
                    end
                end
                
                narazeni(1,1)=populacja-zarazeni(1,1)-ozdrowiency(1,1);
                wskZdr=(ozdrowiency(1,1)/zarazeni(1,1))/100 ;
                
                for i=2:31   
                   
                    narazeniLiczenie = -infWsk*(narazeni(i-1,1))*zarazeni(i-1,1);                   
                    if(-narazeniLiczenie>narazeni(i,1))
                        narazeniLiczenie = -narazeni(i,1)
                    end              
                    narazeni(i,1) = narazeni(i-1,1)+narazeniLiczenie;
                                
                    ozdrowiencyLiczenie = wskZdr * zarazeni((i-1),1);
                    ozdrowiency(i,1) = ozdrowiencyLiczenie+ ozdrowiency((i-1),1);
                    
                    zmarliLiczenie = (zarazeni(i-1,1)*smWsk);                      
                    zmarli(i,1) = zmarli(i-1,1)+zmarliLiczenie;
                    
                    zarazeniLiczenie = (-narazeniLiczenie-ozdrowiencyLiczenie-zmarliLiczenie);
                    zarazeni(i,1) = zarazeni((i-1),1)+zarazeniLiczenie;            


                end

                cla(app.UIAxes);
                hold(app.UIAxes, "on")  
                if app.ZaraeniCheckBox.Value         
                    plot(app.UIAxes, zarazeni, 'r-');
                end
                if app.ZmarliCheckBox.Value
                    plot(app.UIAxes, zmarli, 'k--');
                end
                if app.OzdrowiecyCheckBox.Value
                    plot(app.UIAxes, ozdrowiency,'bo');
                end
                plot1 = "";
                plot2 = "";
                plot3 = "";
                if app.ZaraeniCheckBox.Value
                    plot1 = 'Zarażeni';
                    if app.ZmarliCheckBox.Value
                        plot2 = 'Zmarli';
                        plot3 = 'Ozdrowieńcy';
                    else
                        plot2 = 'Ozdrowiency';
                    end
                else                
                    if app.ZmarliCheckBox.Value
                    plot1 = 'Zmarli';
                    plot2 = 'Ozdrowieńcy';
                    else        
                    plot1 = 'Ozdrowieńcy';
                    end
                end
                legend(app.UIAxes, plot1, plot2, plot3);
               
                hold(app.UIAxes, "off")
                zarazeni
                ozdrowiency
                zmarli
                narazeni
                clearvars -except panstwo zarazeni zmarli ozdrowiency app.LiczySwitch.Value
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ListBoxValueChanged(app, event)
            valueCountry = app.UruchomiListBox.Value;
        end

        % Value changed function: LiczySwitch
        function LiczySwitchValueChanged(app, event)
            liczyValue = app.LiczySwitch.Value;
            if liczyValue == 'Tak'
                clc
                clearvars -except liczyValue valueCountry app.UruchomiListBox.Value app
                [~,~,~,Country_Region,~,~,~, Confirmed,Deaths, Recovered, ~, ~,Incidence_Rate, CaseFatality_Ratio] = readvars('tabela.csv')
                [Panstwo, Liczba_populacji] = readvars('Populacja.csv');
                Panstwo=string(Panstwo);
                Liczba_populacji=double(Liczba_populacji);
                Country_Region=string(Country_Region);
                Confirmed=double(Confirmed);
                Deaths=double(Deaths);
                Recovered=double(Recovered);
                Incidence_Rate=double(Incidence_Rate);
                CaseFatality_Ratio=double(CaseFatality_Ratio);
                zbieranieDanych(app,app.UruchomiListBox.Value, Panstwo, Liczba_populacji, Country_Region, Deaths, Confirmed, Recovered, Incidence_Rate, CaseFatality_Ratio);
                app.LiczySwitch.Value = 'Nie';      
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Potencjalne zarażenia oraz wyzdrowienia')
            xlabel(app.UIAxes, 'Czas (dni)')
            ylabel(app.UIAxes, 'Liczba osób')
            app.UruchomiListBox = uilistbox(app.UIFigure);
            app.UruchomiListBox.Items = {'Afghanistan', 'Albania', 'Algeria', 'Andorra', 'Angola', 'Antigua and Barbuda', 'Argentina', 'Armenia', 'Australia', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Bangladesh', 'Barbados', 'Belarus', 'Belgium', 'Belize', 'Benin', 'Bhutan', 'Bolivia', 'Bosnia and Herzegovina', 'Botswana', 'Brazil', 'Brunei', 'Bulgaria', 'Burkina Faso', 'Burundi', 'Cote d''Ivoire', 'Cabo Verde', 'Cambodia', 'Cameroon', 'Canada', 'Central African Republic', 'Chad', 'Chile', 'China', 'Colombia', 'Comoros', 'Congo (Brazzaville)', 'Costa Rica', 'Croatia', 'Cuba', 'Cyprus', 'Czechia', 'Congo (Kinshasa)', 'Denmark', 'Djibouti', 'Dominica', 'Dominican Republic', 'Ecuador', 'Egypt', 'El Salvador', 'Equatorial Guinea', 'Eritrea', 'Estonia', 'Eswatini', 'Ethiopia ', 'Fiji', 'Finland', 'France', 'Gabon', 'Gambia', 'Georgia', 'Germany', 'Ghana', 'Greece', 'Grenada', 'Guatemala', 'Guinea', 'Guinea-Bissau', 'Guyana', 'Haiti', 'Holy See', 'Honduras', 'Hungary', 'Iceland', 'India', 'Indonesia', 'Iran', 'Iraq', 'Ireland', 'Israel', 'Italy', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Kiribati', 'Kuwait', 'Kyrgyzstan', 'Laos', 'Latvia', 'Lebanon', 'Lesotho', 'Liberia', 'Libya', 'Liechtenstein', 'Lithuania', 'Luxembourg', 'Madagascar', 'Malawi', 'Malaysia', 'Maldives', 'Mali', 'Malta', 'Marshall Islands', 'Mauritania', 'Mauritius', 'Mexico', 'Micronesia', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Burma', 'Namibia', 'Nauru', 'Nepal', 'Netherlands', 'New Zealand', 'Nicaragua', 'Niger', 'Nigeria', 'North Korea', 'North Macedonia', 'Norway', 'Oman', 'Pakistan', 'Palau', 'Palestine State', 'Panama', 'Papua New Guinea', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Qatar', 'Romania', 'Russia', 'Rwanda', 'Saint Kitts and Nevis', 'Saint Lucia', 'Saint Vincent and the Grenadines', 'Samoa', 'San Marino', 'Sao Tome and Principe', 'Saudi Arabia', 'Senegal', 'Serbia', 'Seychelles', 'Sierra Leone', 'Singapore', 'Slovakia', 'Slovenia', 'Solomon Islands', 'Somalia', 'South Africa', 'South Korea', 'South Sudan', 'Spain', 'Sri Lanka', 'Sudan', 'Suriname', 'Sweden', 'Switzerland', 'Syria', 'Tajikistan', 'Tanzania', 'Thailand', 'Timor-Leste', 'Togo', 'Tonga', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Turkmenistan ', 'Tuvalu', 'Uganda', 'Ukraine', 'United Arab Emirates', 'United Kingdom', 'US', 'Uruguay', 'Uzbekistan', 'Vanuatu', 'Venezuela', 'Vietnam', 'Yemen', 'Zambia', 'Zimbabwe'};
            app.UruchomiListBox.Value = 'Afghanistan';
            app.LiczySwitchLabel = uilabel(app.UIFigure);
            app.LiczySwitchLabel.Text = 'Liczyć?';
            app.LiczySwitch = uiswitch(app.UIFigure, 'toggle');
            app.LiczySwitch.Items = {'Nie', 'Tak'};
            app.LiczySwitch.ValueChangedFcn = createCallbackFcn(app, @LiczySwitchValueChanged, true);
            app.LiczySwitch.Value = 'Nie';
            app.ZmarliCheckBox = uicheckbox(app.UIFigure);
            app.ZmarliCheckBox.Text = 'Zmarli?';
            app.ZaraeniCheckBox = uicheckbox(app.UIFigure);
            app.ZaraeniCheckBox.Text = 'Zarażeni?';
            app.OzdrowiecyCheckBox = uicheckbox(app.UIFigure);
            app.OzdrowiecyCheckBox.Text = 'Ozdrowieńcy?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kolke_185ic_projekt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end